classdef App_ideal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ClearGraphButton              matlab.ui.control.Button
        LockGraphforComparisonButton  matlab.ui.control.Button
        AntennaDimensionsEditField    matlab.ui.control.NumericEditField
        FrequencyEditField            matlab.ui.control.NumericEditField
        AntennaDimensionsSlider       matlab.ui.control.Slider
        AntennaDimensionsLabel        matlab.ui.control.Label
        FrequencyMHzSlider            matlab.ui.control.Slider
        FrequencyLabel                matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IsLocked = false; % Description
    end
    
    methods (Access = private)

        function updatePlot(app)
    % 1. Pack UI Inputs into Params Struct
    params.freq_Hz = app.FrequencyEditField.Value * 1e6;
    params.D_m = app.AntennaDimensionsEditField.Value / 100;
    params.P_tx_dBm = 20; 
    params.n_path = 2; 
    params.eff_rectenna = 0.60;
    params.eta_ap = 0.60;
    params.d_vec = linspace(0.1, 5, 500);
    
    % 2. Call the External Physics Engine
    results = wpt_farfield_model(params);
    
    % 3. Handle Lock State
    if ~app.IsLocked
        cla(app.UIAxes, 'reset'); 
    end
    hold(app.UIAxes, 'on'); % Always ensure hold is on to allow plotting
    
    % 4. Render Graph
    plot(app.UIAxes, results.d_vec, results.eff_system_pct, 'LineWidth', 2, ...
         'DisplayName', sprintf('%.2f MHz, %d cm', results.freq_MHz, round(results.D_cm)));
    legend(app.UIAxes, 'show');
    
    app.UIAxes.XLabel.String = 'Distance (meters)';
    app.UIAxes.YLabel.String = 'Efficiency (%)';
    
    % 5. UI Titles and Bounds
    if all(results.invalid_indices)
        app.UIAxes.Title.String = sprintf('Warning: Far-Field begins at %.2f m.', results.near_field_boundary_m);
        app.UIAxes.Title.Color = 'red';
    else
        app.UIAxes.Title.String = sprintf('Far-Field Link (Valid beyond %.2f m)', results.near_field_boundary_m);
        app.UIAxes.Title.Color = 'black';
    end
    
    max_eff = max(results.eff_system_pct);
    if isnan(max_eff)
        ylim(app.UIAxes, [0, 1]); 
    elseif max_eff > 100
        ylim(app.UIAxes, [0, 100]); 
    else
        ylim(app.UIAxes, 'auto');
    end
end
        end 

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            % Update the slider to match the typed number using log scale logic
    % (If your slider limits are 0 to 10)
    app.FrequencyMHzSlider.Value = log10(value * 1e6); 
    
    % Run the math
    updatePlot(app);
        end

        % Value changed function: FrequencyMHzSlider
        function FrequencyMHzSliderValueChanged(app, event)
            value = app.FrequencyMHzSlider.Value;
            % Convert log scale back to MHz and update the numeric field
    app.FrequencyEditField.Value = (10^value) / 1e6;
    
    % Run the math
    updatePlot(app);
        end

        % Value changed function: AntennaDimensionsEditField
        function AntennaDimensionsEditFieldValueChanged(app, event)
            value = app.AntennaDimensionsEditField.Value;
            
            app.AntennaDimensionsSlider.Value = value;
            % Run the math
        updatePlot(app);
        end

        % Value changed function: AntennaDimensionsSlider
        function AntennaDimensionsSliderValueChanged(app, event)
            value = app.AntennaDimensionsSlider.Value;
            
            app.AntennaDimensionsEditField.Value = value;

            % Run the math
    updatePlot(app);
        end

        % Button pushed function: LockGraphforComparisonButton
        function LockGraphforComparisonButtonPushed(app, event)
           app.IsLocked = true; % Correctly sets the flag instead of just holding
        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
     app.IsLocked = false;
    cla(app.UIAxes, 'reset');
    hold(app.UIAxes, 'off');
    updatePlot(app);
    
    % Turn hold off just to be safe
    hold(app.UIAxes, 'off');
    
    % Force the plot to update immediately with the current slider values
    updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes')
            xlabel(app.UIAxes, 'Distance (m)')
            ylabel(app.UIAxes, 'Efficiency (%)')
            zlabel(app.UIAxes, 'Z')
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = {'Frequency'; 'MHz'};
            app.FrequencyMHzSlider = uislider(app.UIFigure);
            app.FrequencyMHzSlider.Limits = [7 10];
            app.FrequencyMHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzSliderValueChanged, true);
            app.FrequencyMHzSlider.Value = 7;
            app.AntennaDimensionsLabel = uilabel(app.UIFigure);
            app.AntennaDimensionsLabel.Text = {'Antenna'; 'Dimensions'; '(cm)'};
            app.AntennaDimensionsSlider = uislider(app.UIFigure);
            app.AntennaDimensionsSlider.Limits = [0 1000];
            app.AntennaDimensionsSlider.ValueChangedFcn = createCallbackFcn(app, @AntennaDimensionsSliderValueChanged, true);
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [9.5 10000];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 9.5;
            app.AntennaDimensionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AntennaDimensionsEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaDimensionsEditFieldValueChanged, true);
            app.LockGraphforComparisonButton = uibutton(app.UIFigure, 'push');
            app.LockGraphforComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @LockGraphforComparisonButtonPushed, true);
            app.LockGraphforComparisonButton.Text = 'Lock Graph for Comparison';
            app.ClearGraphButton = uibutton(app.UIFigure, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ideal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end